classdef DiffusionModelApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DiffusionModel                  matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        OneSimulationTab                matlab.ui.container.Tab
        LoadImagePanel                  matlab.ui.container.Panel
        dxmLabel                        matlab.ui.control.Label
        dx                              matlab.ui.control.NumericEditField
        ImageDropDownLabel              matlab.ui.control.Label
        ImageDropDown                   matlab.ui.control.DropDown
        MaskDropDownLabel               matlab.ui.control.Label
        MaskDropDown                    matlab.ui.control.DropDown
        dymLabel                        matlab.ui.control.Label
        dy                              matlab.ui.control.NumericEditField
        dzmLabel                        matlab.ui.control.Label
        dz                              matlab.ui.control.NumericEditField
        PixelSizeLabel                  matlab.ui.control.Label
        ImageSizeLabel                  matlab.ui.control.Label
        MzEditFieldLabel                matlab.ui.control.Label
        Mz                              matlab.ui.control.NumericEditField
        MyEditFieldLabel                matlab.ui.control.Label
        My                              matlab.ui.control.NumericEditField
        MxEditFieldLabel                matlab.ui.control.Label
        Mx                              matlab.ui.control.NumericEditField
        SourcepointLabel                matlab.ui.control.Label
        ZEditFieldLabel                 matlab.ui.control.Label
        Z0                              matlab.ui.control.NumericEditField
        YEditFieldLabel                 matlab.ui.control.Label
        Y0                              matlab.ui.control.NumericEditField
        XEditFieldLabel                 matlab.ui.control.Label
        X0                              matlab.ui.control.NumericEditField
        InitialPixelLabel               matlab.ui.control.Label
        YEditField_2Label               matlab.ui.control.Label
        idx1                            matlab.ui.control.NumericEditField
        XEditField_2Label               matlab.ui.control.Label
        idx2                            matlab.ui.control.NumericEditField
        coment                          matlab.ui.control.Label
        LoadButton                      matlab.ui.control.Button
        CorrectioncoeffforblurredimagesLabel  matlab.ui.control.Label
        Aux                             matlab.ui.control.NumericEditField
        SimPanel                        matlab.ui.container.Panel
        ModelDropDownLabel              matlab.ui.control.Label
        ModelDropDown                   matlab.ui.control.DropDown
        TimeLabel                       matlab.ui.control.Label
        SourcedurationEditFieldLabel    matlab.ui.control.Label
        Sourceduration                  matlab.ui.control.NumericEditField
        TimestepEditFieldLabel          matlab.ui.control.Label
        Timestep                        matlab.ui.control.NumericEditField
        TotalDurationEditFieldLabel     matlab.ui.control.Label
        TotalDuration                   matlab.ui.control.NumericEditField
        ClearanceLabel                  matlab.ui.control.Label
        Kappa                           matlab.ui.control.NumericEditField
        De10m2sLabel                    matlab.ui.control.Label
        TimeUnitsLabel                  matlab.ui.control.Label
        Units                           matlab.ui.control.DropDown
        FreeDifCoefficientLabel         matlab.ui.control.Label
        Dif                             matlab.ui.control.NumericEditField
        BasalConcentrationMLabel        matlab.ui.control.Label
        Cbasal                          matlab.ui.control.NumericEditField
        BCLabel                         matlab.ui.control.Label
        Boundaries                      matlab.ui.control.DropDown
        FrequencyHzLabel                matlab.ui.control.Label
        Freq                            matlab.ui.control.NumericEditField
        PropertiesPanel                 matlab.ui.container.Panel
        VoidvolumeLabel                 matlab.ui.control.Label
        Alpha                           matlab.ui.control.NumericEditField
        ApparentDifCoefficeintLabel     matlab.ui.control.Label
        Dmean                           matlab.ui.control.NumericEditField
        TortuosityLabel                 matlab.ui.control.Label
        Lamda                           matlab.ui.control.NumericEditField
        e10m2sLabel                     matlab.ui.control.Label
        Ax2                             matlab.ui.control.UIAxes
        SourcePanel                     matlab.ui.container.Panel
        TransportnumberEditFieldLabel_3  matlab.ui.control.Label
        TN                              matlab.ui.control.NumericEditField
        CurrentpAEditFieldLabel_3       matlab.ui.control.Label
        Current                         matlab.ui.control.NumericEditField
        ISCheckBox                      matlab.ui.control.CheckBox
        UnitVolumem3Label_2             matlab.ui.control.Label
        UV                              matlab.ui.control.NumericEditField
        NofparticlesLabel_2             matlab.ui.control.Label
        Particles                       matlab.ui.control.NumericEditField
        ConcentrationuMLabel_2          matlab.ui.control.Label
        C0                              matlab.ui.control.NumericEditField
        PSCheckBox                      matlab.ui.control.CheckBox
        ConcentrationuMLabel            matlab.ui.control.Label
        C0_2                            matlab.ui.control.NumericEditField
        Maxnumberofparticlesperum3Label  matlab.ui.control.Label
        Maxnump                         matlab.ui.control.NumericEditField
        SavePanel                       matlab.ui.container.Panel
        FileNameLabel_3                 matlab.ui.control.Label
        FileName                        matlab.ui.control.EditField
        SaverateLabel                   matlab.ui.control.Label
        SaveRate                        matlab.ui.control.NumericEditField
        coment_3                        matlab.ui.control.Label
        StartButton                     matlab.ui.control.Button
        VideoPanel                      matlab.ui.container.Panel
        coment_4                        matlab.ui.control.Label
        ConcentrationlimitsLabel        matlab.ui.control.Label
        toLabel_2                       matlab.ui.control.Label
        Clim2                           matlab.ui.control.NumericEditField
        fromLabel_2                     matlab.ui.control.Label
        Clim1                           matlab.ui.control.NumericEditField
        playButton                      matlab.ui.control.Button
        UnitsDropDownLabel_3            matlab.ui.control.Label
        UnitsC                          matlab.ui.control.DropDown
        FramespersecondLabel            matlab.ui.control.Label
        fps                             matlab.ui.control.NumericEditField
        FileNameLabel_7                 matlab.ui.control.Label
        FileName_6                      matlab.ui.control.EditField
        MultipleSimulationsTab          matlab.ui.container.Tab
        PropertiesPanel_2               matlab.ui.container.Panel
        VoidvolumeLabel_2               matlab.ui.control.Label
        Alpha_2                         matlab.ui.control.NumericEditField
        DiffusionCoefficeintDLabel_2    matlab.ui.control.Label
        Dmean_2                         matlab.ui.control.NumericEditField
        TortuosityLabel_2               matlab.ui.control.Label
        Lamda_2                         matlab.ui.control.NumericEditField
        e10m2sLabel_2                   matlab.ui.control.Label
        LoadImagePanel_2                matlab.ui.container.Panel
        dxmLabel_2                      matlab.ui.control.Label
        dx_2                            matlab.ui.control.NumericEditField
        dymLabel_2                      matlab.ui.control.Label
        dy_2                            matlab.ui.control.NumericEditField
        dzmLabel_2                      matlab.ui.control.Label
        dz_2                            matlab.ui.control.NumericEditField
        ImageDropDown_2Label            matlab.ui.control.Label
        ImageDropDown_2                 matlab.ui.control.DropDown
        MaskDropDown_2Label             matlab.ui.control.Label
        MaskDropDown_2                  matlab.ui.control.DropDown
        PixelSizeLabel_2                matlab.ui.control.Label
        ImageSizeLabel_2                matlab.ui.control.Label
        MzEditFieldLabel_2              matlab.ui.control.Label
        Mz_2                            matlab.ui.control.NumericEditField
        MyEditFieldLabel_2              matlab.ui.control.Label
        My_2                            matlab.ui.control.NumericEditField
        MxEditFieldLabel_2              matlab.ui.control.Label
        Mx_2                            matlab.ui.control.NumericEditField
        IndexesLabel                    matlab.ui.control.Label
        YEditField_2Label_2             matlab.ui.control.Label
        Y0_2                            matlab.ui.control.NumericEditField
        XEditField_2Label_2             matlab.ui.control.Label
        X0_2                            matlab.ui.control.NumericEditField
        coment_2                        matlab.ui.control.Label
        LoadButton_2                    matlab.ui.control.Button
        SourceFileLabel                 matlab.ui.control.Label
        SourceDropDown                  matlab.ui.control.DropDown
        toLabel                         matlab.ui.control.Label
        idxend                          matlab.ui.control.NumericEditField
        fromLabel                       matlab.ui.control.Label
        idx0                            matlab.ui.control.NumericEditField
        ZEditFieldLabel_2               matlab.ui.control.Label
        Z0_2                            matlab.ui.control.NumericEditField
        SimPanel_2                      matlab.ui.container.Panel
        ModelDropDown_2Label            matlab.ui.control.Label
        ModelDropDown_2                 matlab.ui.control.DropDown
        ClearanceLabel_2                matlab.ui.control.Label
        Kappa_2                         matlab.ui.control.NumericEditField
        TimeLabel_2                     matlab.ui.control.Label
        SourcedurationEditFieldLabel_2  matlab.ui.control.Label
        Sourceduration_2                matlab.ui.control.NumericEditField
        TimestepEditFieldLabel_2        matlab.ui.control.Label
        Timestep_2                      matlab.ui.control.NumericEditField
        TotalDurationEditFieldLabel_2   matlab.ui.control.Label
        TotalDuration_2                 matlab.ui.control.NumericEditField
        FileNameLabel_2                 matlab.ui.control.Label
        FileName_2                      matlab.ui.control.EditField
        UnitsDropDownLabel_2            matlab.ui.control.Label
        Units_2                         matlab.ui.control.DropDown
        SaverateLabel_2                 matlab.ui.control.Label
        SaveRate_2                      matlab.ui.control.NumericEditField
        coment_5                        matlab.ui.control.Label
        De10m2sLabel_2                  matlab.ui.control.Label
        FreeDifCoefficientLabel_2       matlab.ui.control.Label
        Dif_2                           matlab.ui.control.NumericEditField
        UnitVolumem3Label_4             matlab.ui.control.Label
        UV_3                            matlab.ui.control.NumericEditField
        NofparticlesLabel_4             matlab.ui.control.Label
        Particles_3                     matlab.ui.control.NumericEditField
        ConcentrationMLabel             matlab.ui.control.Label
        C0_4                            matlab.ui.control.NumericEditField
        ParticleSourceLabel             matlab.ui.control.Label
        MaxNofparticlesLabel            matlab.ui.control.Label
        Maxnump2                        matlab.ui.control.NumericEditField
        BCLabel_2                       matlab.ui.control.Label
        Boundaries2                     matlab.ui.control.DropDown
        Ax3                             matlab.ui.control.UIAxes
        VideoPanel_2                    matlab.ui.container.Panel
        coment_6                        matlab.ui.control.Label
        ConcentrationlimitsLabel_2      matlab.ui.control.Label
        toLabel_4                       matlab.ui.control.Label
        Clim2_2                         matlab.ui.control.NumericEditField
        fromLabel_4                     matlab.ui.control.Label
        Clim1_2                         matlab.ui.control.NumericEditField
        UnitsDropDownLabel_4            matlab.ui.control.Label
        UnitsC_2                        matlab.ui.control.DropDown
        FramespersecondLabel_2          matlab.ui.control.Label
        fps_2                           matlab.ui.control.NumericEditField
        StartButton_2                   matlab.ui.control.Button
        TIFFImageTab                    matlab.ui.container.Tab
        SaveTIFFPanel                   matlab.ui.container.Panel
        NumberofframesLabel             matlab.ui.control.Label
        numf                            matlab.ui.control.NumericEditField
        toLabel_3                       matlab.ui.control.Label
        fend                            matlab.ui.control.NumericEditField
        fromLabel_3                     matlab.ui.control.Label
        f1                              matlab.ui.control.NumericEditField
        FramelimitsLabel                matlab.ui.control.Label
        Save                            matlab.ui.control.Button
        FileNameLabel_4                 matlab.ui.control.Label
        FileName_3                      matlab.ui.control.EditField
        ModelDropDown_3Label            matlab.ui.control.Label
        ModelDropDown_3                 matlab.ui.control.DropDown
        coment_7                        matlab.ui.control.Label
        ConcentrationlimitsLabel_3      matlab.ui.control.Label
        toLabel_5                       matlab.ui.control.Label
        Clim2_3                         matlab.ui.control.NumericEditField
        fromLabel_5                     matlab.ui.control.Label
        Clim1_3                         matlab.ui.control.NumericEditField
        UnitsDropDownLabel_5            matlab.ui.control.Label
        UnitsC_3                        matlab.ui.control.DropDown
        AnalysisTab                     matlab.ui.container.Tab
        TensorMap                       matlab.ui.control.Button
        ChooseAnalysisLabel             matlab.ui.control.Label
        DiffusionCurves                 matlab.ui.control.Button
        CompareModels                   matlab.ui.control.Button
        CompareMolec                    matlab.ui.control.Button
        DiffusionCurves_4               matlab.ui.control.Button
        DiffusionCurves_5               matlab.ui.control.Button
        CoeffVar                        matlab.ui.control.Button
        Parameters1Panel                matlab.ui.container.Panel
        FileNameLabel_5                 matlab.ui.control.Label
        FileName_4                      matlab.ui.control.EditField
        PixelSizeLabel_3                matlab.ui.control.Label
        dxmLabel_3                      matlab.ui.control.Label
        dx_3                            matlab.ui.control.NumericEditField
        dymLabel_3                      matlab.ui.control.Label
        dy_3                            matlab.ui.control.NumericEditField
        dzmLabel_3                      matlab.ui.control.Label
        dz_3                            matlab.ui.control.NumericEditField
        ImageSizeLabel_3                matlab.ui.control.Label
        MzEditFieldLabel_3              matlab.ui.control.Label
        Mz_3                            matlab.ui.control.NumericEditField
        MyEditFieldLabel_3              matlab.ui.control.Label
        My_3                            matlab.ui.control.NumericEditField
        MxEditFieldLabel_3              matlab.ui.control.Label
        Mx_3                            matlab.ui.control.NumericEditField
        SourcepointLabel_2              matlab.ui.control.Label
        ZEditFieldLabel_3               matlab.ui.control.Label
        Z0_3                            matlab.ui.control.NumericEditField
        YEditFieldLabel_2               matlab.ui.control.Label
        Y0_3                            matlab.ui.control.NumericEditField
        XEditFieldLabel_2               matlab.ui.control.Label
        X0_3                            matlab.ui.control.NumericEditField
        MoleculeLabel                   matlab.ui.control.Label
        Molec                           matlab.ui.control.EditField
        ModelLabel                      matlab.ui.control.Label
        Model                           matlab.ui.control.EditField
        InitialPixelLabel_2             matlab.ui.control.Label
        YEditField_2Label_3             matlab.ui.control.Label
        idx1_2                          matlab.ui.control.NumericEditField
        XEditField_2Label_3             matlab.ui.control.Label
        idx2_2                          matlab.ui.control.NumericEditField
        ImageDropDown_5Label            matlab.ui.control.Label
        ImageDropDown_5                 matlab.ui.control.DropDown
        varianceframesizeLabel          matlab.ui.control.Label
        var_size                        matlab.ui.control.NumericEditField
        AnalysisPlot                    matlab.ui.control.UIAxes
        Parameters2Panel                matlab.ui.container.Panel
        FileNameLabel_6                 matlab.ui.control.Label
        FileName_5                      matlab.ui.control.EditField
        PixelSizeLabel_4                matlab.ui.control.Label
        dxmLabel_4                      matlab.ui.control.Label
        dx_4                            matlab.ui.control.NumericEditField
        dymLabel_4                      matlab.ui.control.Label
        dy_4                            matlab.ui.control.NumericEditField
        dzmLabel_4                      matlab.ui.control.Label
        dz_4                            matlab.ui.control.NumericEditField
        ImageSizeLabel_4                matlab.ui.control.Label
        MzEditFieldLabel_4              matlab.ui.control.Label
        Mz_4                            matlab.ui.control.NumericEditField
        MyEditFieldLabel_4              matlab.ui.control.Label
        My_4                            matlab.ui.control.NumericEditField
        MxEditFieldLabel_4              matlab.ui.control.Label
        Mx_4                            matlab.ui.control.NumericEditField
        SourcepointLabel_3              matlab.ui.control.Label
        ZEditFieldLabel_4               matlab.ui.control.Label
        Z0_4                            matlab.ui.control.NumericEditField
        YEditFieldLabel_3               matlab.ui.control.Label
        Y0_4                            matlab.ui.control.NumericEditField
        XEditFieldLabel_3               matlab.ui.control.Label
        X0_4                            matlab.ui.control.NumericEditField
        MoleculeLabel_2                 matlab.ui.control.Label
        Molec_2                         matlab.ui.control.EditField
        ModelLabel_2                    matlab.ui.control.Label
        Model_2                         matlab.ui.control.EditField
        InitialPixelLabel_3             matlab.ui.control.Label
        YEditField_2Label_4             matlab.ui.control.Label
        idx1_3                          matlab.ui.control.NumericEditField
        XEditField_2Label_4             matlab.ui.control.Label
        idx2_3                          matlab.ui.control.NumericEditField
        StartAnalysis                   matlab.ui.control.Button
        CLLabel_4                       matlab.ui.control.Label
        toLabel_6                       matlab.ui.control.Label
        Clim2_4                         matlab.ui.control.NumericEditField
        fromLabel_6                     matlab.ui.control.Label
        Clim1_4                         matlab.ui.control.NumericEditField
        UnitsDropDownLabel_8            matlab.ui.control.Label
        UnitsC_4                        matlab.ui.control.DropDown
        Tensorpannel                    matlab.ui.container.Panel
        ImageDropDown_3Label            matlab.ui.control.Label
        ImageDropDown_3                 matlab.ui.control.DropDown
        TensorMapLabel                  matlab.ui.control.Label
        MSBox                           matlab.ui.control.CheckBox
        ArrowscaleLabel                 matlab.ui.control.Label
        scale                           matlab.ui.control.NumericEditField
        TransparencyLabel               matlab.ui.control.Label
        alpha                           matlab.ui.control.NumericEditField
        Label                           matlab.ui.control.Label
        XaxisLabel                      matlab.ui.control.Label
        Xs                              matlab.ui.control.NumericEditField
        SquaresLabel                    matlab.ui.control.Label
        YaxisLabel                      matlab.ui.control.Label
        Ys                              matlab.ui.control.NumericEditField
        RSQcoeficienttresholdLabel      matlab.ui.control.Label
        rsq_treshold                    matlab.ui.control.NumericEditField
        ShowRTIcurvesLabel              matlab.ui.control.Label
        RTIbox                          matlab.ui.control.CheckBox
        RTIbox_2                        matlab.ui.control.CheckBox
        RTIbox_3                        matlab.ui.control.CheckBox
        Units_3                         matlab.ui.control.DropDown
        TLabel                          matlab.ui.control.Label
        MPlabel                         matlab.ui.control.Label
        rad                             matlab.ui.control.NumericEditField
        mLabel                          matlab.ui.control.Label
        RTIbox_4                        matlab.ui.control.CheckBox
        RTIbox_5                        matlab.ui.control.CheckBox
        RTIbox_6                        matlab.ui.control.CheckBox
        Save_2                          matlab.ui.control.Button
        Cprofile                        matlab.ui.control.Button
        BasalConcentrationuMLabel_2     matlab.ui.control.Label
        Cbasal_2                        matlab.ui.control.NumericEditField
        CurvefittingLabel               matlab.ui.control.Label
        FitBox                          matlab.ui.control.CheckBox
        AnalyseImageTab                 matlab.ui.container.Tab
        AnalyseImagePanel               matlab.ui.container.Panel
        ImageDropDown_4Label            matlab.ui.control.Label
        ImageDropDown_4                 matlab.ui.control.DropDown
        LoadButton_3                    matlab.ui.control.Button
        AnalyseImagetoestimatevolumefractionandtortuosityLabel  matlab.ui.control.Label
        VoidvolumeLabel_3               matlab.ui.control.Label
        Alpha_3                         matlab.ui.control.NumericEditField
        TortuosityLabel_3               matlab.ui.control.Label
        Lamda_3                         matlab.ui.control.NumericEditField
        EstimatedPropertiesLabel        matlab.ui.control.Label
        Ax2_2                           matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        mainfolder
        imfolder
        resfolder
        IM
        ds
        S0
        M
        dt
        mask
        data
        Cin
        Cmax
        Tab
        Analysis
        unittime
        unitconc
        fig1
        bounds
        freq
        op


    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Define folders
            
            app.mainfolder = 'C:\Users\Paula\Documents\MATLAB\Diffusion Model 4.2\';
            app.imfolder = 'D:\MATLAB\Diffusion Model 4.2\Images\';
            app.resfolder = 'D:\MATLAB\Diffusion Model 4.2\Results\';
            
            % Disable UI components
            panels = [app.PropertiesPanel, app.SimPanel, app.SourcePanel,app.SavePanel, app.VideoPanel, ...
                      app.PropertiesPanel_2, app.SimPanel_2, app.VideoPanel_2, app.Parameters1Panel, app.Parameters2Panel];
            set(findall(panels, '-property', 'enable'), 'enable', 'off');
            
            buttonsAndLabels = [app.StartAnalysis, app.CLLabel_4, app.Clim1_4, app.Clim2_4, app.UnitsC_4, app.RTIbox, ... 
                                app.ShowRTIcurvesLabel, app.RTIbox_2, app.RTIbox_3, app.RTIbox_4, app.RTIbox_5, app.RTIbox_6, ...
                                app.TLabel, app.Units_3, app.MPlabel, app.rad, app.mLabel, app.StartButton, app.playButton, app.StartButton_2, app.Cbasal_2,app.FitBox];
            set(buttonsAndLabels, 'Enable', 'off');
            
            % Hide UI axes
            set(app.AnalysisPlot,'visible', 'off')
            set(app.Tensorpannel,'visible', 'off')
            
            % Perform any other necessary initialization
            defaultconfiguration(app);
            app.Tab = app.TabGroup.SelectedTab.Title;
        end

        % Button pushed function: StartButton, StartButton_2
        function StartButtonPushed(app, event)
            % Disable Start buttons
            buttons=[app.StartButton, app.StartButton_2];
            set(buttons,'Enable', 'off');
            
            % Load parameters and solve equations
            [rate,app.dt,nt,op,~,~,~,dif,tp,T,K,SName,Cb,clim,NameMask,app.unittime,app.unitconc,app.bounds,app.freq]=LoadParam(app);
            [lamda,dif_av,app.data]=SolveEquations(op,rate,nt,dif,tp,T,K,SName,app,Cb,clim,NameMask);

             % Update UI elements based on selected tab
            if strcmp(app.Tab,'Multiple Simulations')==1
                app.Lamda_2.Value=round(lamda,2);
                app.Dmean_2.Value=round(dif_av * 10^10,2);
                
                buttonsAndLabels=[app.Alpha_2, app.Dmean_2, app.Lamda_2];
                panels=[app.PropertiesPanel_2];

            else
                app.Lamda.Value=round(lamda,2);
                app.Dmean.Value=round(dif_av*10^10,2);
                app.FileName_6.Value=app.FileName.Value;
                
                buttonsAndLabels=[app.Alpha, app.Dmean, app.Lamda, app.playButton];
                panels=[app.PropertiesPanel, app.VideoPanel];
 
            end
            
            % Enable UI components
            set(buttonsAndLabels,'Enable', 'on');
            set(findall(panels, '-property', 'enable'), 'enable', 'on')

        end

        % Button pushed function: LoadButton, LoadButton_2
        function LoadButtonPushed(app, event)
            app.Tab=app.TabGroup.SelectedTab.Title;
            [~,~,~,app.op,app.S0,app.M,app.ds,~,~,~,~,~,~,~,~,~,~,~]=LoadParam(app);
            [volfraction,IM2,Mask]=LoadImage(app);
            datestr1=string(datetime("today","Format",'yyyy-MM-dd'));
            dates= strsplit(datestr1,'-');
            datestr2=[dates{1},dates{2},dates{3}];
            
            app.IM=IM2;
            app.mask=Mask;
            
            if strcmp(app.Tab,'Multiple Simulations')==1
                app.Alpha_2.Value=round(volfraction,2);
                panelsToEnable=[app.SimPanel_2, app.VideoPanel_2];
                panelsToDisable=app.PropertiesPanel_2;
                buttonsToEnable=app.StartButton_2;  
                app.FileName_2.Value=datestr2;
            else
               app.Alpha.Value=round(volfraction,2);
               panelsToEnable=[app.SimPanel, app.VideoPanel, app.SourcePanel, app.SavePanel];
               panelsToDisable=app.PropertiesPanel;
               buttonsToEnable=[app.StartButton, app.playButton, app.ISCheckBox, app.PSCheckBox];
               app.FileName.Value=[datestr2,'_01'];  
            end
            
            % Enable or disable components
            set(findall(panelsToEnable, '-property', 'enable'), 'enable', 'on');
            set(findall(panelsToDisable, '-property', 'enable'), 'enable', 'off');
            set(buttonsToEnable,'Enable', 'on');
        end

        % Button pushed function: playButton
        function playButtonPushed(app, event)
            
            [rate,timestep,nt,op,~,Msize,~,~,~,~,~,~,Cb,clim,NameMask,unitt,unitc,~]=LoadParam(app);
            
            % Load data from file
            SName=app.FileName_6.Value;            
            file=[app.resfolder SName '.mat'];
            load(file);
            
            %Update parameters
            MS=0;

            if op == 1 || op == 2 || op == 4
                NameVideo=[app.resfolder,SName,'.avi'];
                SaveVideo(Data,NameVideo,app.IM,nt,rate,timestep,t,unitc,unitt,clim,Cb,app.fps.Value,NameMask,MS);
            elseif op==3
                % Extract 2D slice from the 3D data
                %Data3D1=squeeze(Data(:,:,round(Msize(3)/2),:));
                Data3D1=squeeze(Data(:,:,app.Z0.Value,:));
%                     Data3D2=squeeze(Data(:,:,round(Msize(3)/4),:));
%                     Data3D3=squeeze(Data(:,:,round(Msize(3)*3/4),:));
                NameVideo1=[app.resfolder,SName,'_z2.avi'];
%                     NameVideo2=[app.resfolder,SName,'_z1.avi'];
%                     NameVideo3=[app.resfolder,SName,'_z3.avi'];
                
                SaveVideo(Data3D1,NameVideo1,app.IM,nt,rate,timestep,t,unitc,unitt,clim,Cb,app.fps.Value,NameMask,MS);
            end
        end

        % Value changed function: ISCheckBox, PSCheckBox
        function ISCheckBoxValueChanged(app, event)
            IS = app.ISCheckBox.Value;
            PS = app.PSCheckBox.Value;
           
            if IS ==1
                op2=1;
                [app.Cin,app.Cmax]=SourceType(app,op2);
     
                app.C0_2.Value=round(app.Cin*10^3,3); % app.C0 Concentration expressed in uM
                
            elseif PS ==1
                op2=2;
                [app.Cin,app.Cmax]=SourceType(app,op2);
                app.C0.Value=round(app.Cin*10^3,3); % app.C0 Concentration expressed in uM  
            else
                disp('A source type must be chosen before running the simulation.')
            end
            %set(app.ISCheckBox,'Enable', 'off');
              
        end

        % Button pushed function: Save
        function SaveButtonPushed(app, event)
            Idx=[app.f1.Value , app.fend.Value];
            Clim=[app.Clim1_3.Value , app.Clim2_3.Value];
            frames=app.numf.Value;
            model=app.ModelDropDown_3.Value;
            fname=app.FileName_3.Value;
            unitC=app.UnitsC_3.Value;
            
            TIFFgenerator(app.resfolder,fname,frames,Idx,model,Clim,unitC)
            
        end

        % Value changed function: Particles_3
        function Particles_3ValueChanged(app, event)

            Nump = app.Particles_3.Value;
            Na=6.022e23; %Avogadro's number
            d3=app.ds(1)*app.ds(2)*app.ds(3);
            V=d3*10^3; %unit volume in dm3 = L
            app.Cin=(Nump/(Na*V))*1e3; % Concentration in mM
            app.UV_3.Value=round(d3*1e18,4);
            app.C0_4.Value=round(app.Cin*1e3,3);
            
            maxnump=app.Maxnump2.Value*1e15*V;%max number of particles in a voxel
            app.Cmax=(maxnump/(Na*V))*1e3; % Concentration in mM
            
            
        end

        % Button pushed function: TensorMap
        function TensorMapButtonPushed(app, event)
            buttonsandlabelsToDisable=[app.RTIbox, app.RTIbox_2, app.RTIbox_3, app.RTIbox_4, app.RTIbox_5, app.RTIbox_6,...
                app.ShowRTIcurvesLabel, app.TLabel, app.Units_3, app.rad, app.MPlabel, app.mLabel, app.FitBox];
            buttonsandlabelsToEnable=[app.StartAnalysis, app.CLLabel_4, app.Clim1_4, app.Clim2_4, app.UnitsC_4];
            pannelsToEnable=[app.Parameters1Panel, app.Tensorpannel];
            pannelsToDisable=app.Parameters2Panel;
            
            set(buttonsandlabelsToDisable,'Enable', 'off');
            set(buttonsandlabelsToEnable,'Enable', 'on');
            set(findall(pannelsToEnable, '-property', 'enable'), 'enable', 'on');
            set(findall(pannelsToDisable, '-property', 'enable'), 'enable', 'off');
            
            set(app.Tensorpannel,'visible', 'on')
            set(app.AnalysisPlot,'visible', 'off')
             
            app.Analysis=1;
            
            
        end

        % Button pushed function: StartAnalysis
        function StartAnalysisButtonPushed(app, event)

            %Disable pannnels and buttons
            pannelsToDisable=[app.Parameters1Panel, app.Tensorpannel, app.Parameters2Panel];
            buttonsandlabelsToDisable=[app.RTIbox, app.RTIbox_2, app.RTIbox_3, app.RTIbox_4, app.RTIbox_5, app.RTIbox_6, ...
                app.ShowRTIcurvesLabel, app.TLabel, app.Units_3, app.rad, app.MPlabel, app.mLabel, app.Cbasal_2, ...
                app.StartAnalysis, app.CLLabel_4, app.Clim1_4, app.Clim2_4, app.UnitsC_4,app.FitBox];
            
            set(findall(pannelsToDisable, '-property', 'enable'), 'enable', 'off');
            set(buttonsandlabelsToDisable,'Enable', 'off');

            % Convert units from SI units to desired ones. 
            unitC=app.UnitsC_4.Value;
            scaleC=convertFromSIunit(unitC);
            unitT=app.Units_3.Value;

            % Get RTI values
            RTI(1) = app.RTIbox.Value;
            RTI(2) = app.RTIbox_2.Value;
            RTI(3) = app.RTIbox_3.Value;
            RTI(4) = app.RTIbox_4.Value;
            RTI(5) = app.RTIbox_5.Value;
            RTI(6) = app.RTIbox_6.Value;
            
            fit=app.FitBox.Value;
            
            % Get dimensions and other parameters
            pixelsize = [app.dy_3.Value*10^-6, app.dx_3.Value*10^-6, app.dz_3.Value*10^-6];
            Msize = [app.My_3.Value, app.Mx_3.Value, app.Mz_3.Value];
            S01 = [app.Y0_3.Value, app.X0_3.Value, app.Z0_3.Value];          
            radius=app.rad.Value*10^-6; % in m
            FN=app.FileName_4.Value;
            molec=app.Molec.Value;
            model=app.Model.Value;
            file=fullfile(app.resfolder, [FN, '.mat']);
            Cb2=app.Cbasal_2.Value*10^-3; % Basal concentration to SI units
            
            % Define color matrix for different models and molecules   
            colorMatrix = [1, 0.4, 0.4; 1, 0.4, 0.4; 1, 0.4, 0;...
                0, 0.8, 0.6; 0.2, 0.6, 0.6; 0, 0.4, 0.6; ...
                0.6, 0.8, 0.8; 0.2, 0.6, 0.6; 0, 0.4, 0.6; ...
                0, 0.8, 1; 0.2, 0.6, 0.6; 0, 0.4, 0.6; ...
                0.4,0,0.8; 0.4,0.4,0.8; 0.4,0.8,0.8];
            
            % Perform different types of analysis based on selected options
            if app.Analysis ==1
                % Perform Tensormap Analysis
                Clim=app.Clim1_4.Value;
                IN=app.ImageDropDown_3.Value;
                idx=[app.idx1_2.Value, app.idx2_2.Value]; %[Y,X]
                MS = app.MSBox.Value;                
                tensormap(FN,IN,Msize,idx,MS,Clim,app.scale.Value,app.imfolder,app.resfolder,app.alpha.Value,...
                    app.Xs.Value,app.Ys.Value,app.rsq_treshold.Value, scaleC);
            
            elseif app.Analysis ==2
                % Perform diffusion curves analysis
                app.fig1=figure('color','w');

                ax=axes('Parent', app.fig1, 'Units', 'centimeters', 'Position', [2, 2, 5, 5], ...
                        'FontSize', 12, 'FontName', 'Arial', 'Box', 'off', 'Color', 'none');   
                set(app.AnalysisPlot,'visible', 'on')
                set(app.Tensorpannel,'visible', 'off')

                NumAnalysis=1;
                
                [P,label]=DiffAnalysis(app.Analysis,file,molec,model,pixelsize,Msize,S01,unitC,unitT,RTI,radius,ax,NumAnalysis,fit);
                
                legend(ax,P,label,'Box','off','FontSize',10);
                
                Name=[app.imfolder,app.ImageDropDown_5.Value];
                size=app.var_size.Value;
                IM_dc=imread(Name);
                [V]=variance_analyzer(IM_dc,S01,size);
             
            elseif app.Analysis ==3 || app.Analysis ==4
                % Compare molecules and models 
                
                 figure;
                 axis;
                 hax=gca;
                set(app.AnalysisPlot,'visible', 'on')
                set(app.Tensorpannel,'visible', 'off')
                
                molec2=app.Molec_2.Value;
                model2=app.Model_2.Value;
                pixelsize2 = [app.dy_4.Value*10^-6, app.dx_4.Value*10^-6, app.dz_4.Value*10^-6];
                Msize2 = [app.My_4.Value, app.Mx_4.Value, app.Mz_4.Value];
                S02 = [app.Y0_4.Value, app.X0_4.Value, app.Z0_4.Value]; 
                FN2=app.FileName_5.Value;
                file2=fullfile(app.resfolder, [FN2, '.mat']);
                
                NumAnalysis=1;
                [P1,label]=DiffAnalysis(app.Analysis,file,molec,model,pixelsize,Msize,S01,unitC,unitT,RTI,radius,hax,NumAnalysis,fit);
                NumAnalysis=2;
                p=P1(1,1);
                str=label{1,1};
                hold(app.AnalysisPlot,'on')
                [P2,Label1]=DiffAnalysis(app.Analysis,file2,molec2,model2,pixelsize2,Msize2,S02,unitC,unitT,RTI,radius,hax,NumAnalysis,fit);
                p3=P2(1,1);
                str3=Label1{1,1};
                if length(P1)>1
                    p2=P1(1,2);
                    str2=label{1,2};
                    P=[p,p3,p2];
                    label={str,str3,str2};
                else
                    P=[p,p3];
                    label={str,str3};
                end
                legend(hax,P,label) 
                
            elseif app.Analysis==5
                % 3D Plot concentration profile over time and distance for
                % basal concentration analysis

                set(app.AnalysisPlot,'visible', 'on')
                set(app.Tensorpannel,'visible', 'off')

                ConcentrationProfile(file,molec,model,pixelsize(2),Msize(2),S01,U,UT,Cb2)
                
            elseif app.Analysis==6
                % Plot coefficient of variation of the concentration 
                
                set(app.AnalysisPlot,'visible', 'on')
                set(app.Tensorpannel,'visible', 'off')

                CoeffofVariationofConcentration(app.Analysis,file,model,pixelsize,Msize,S01,unitC)

            end
            
            
        end

        % Button pushed function: DiffusionCurves
        function DiffusionCurvesButtonPushed(app, event)
            set(app.Tensorpannel,'visible', 'off')
            set(findall(app.Parameters1Panel, '-property', 'enable'), 'enable', 'on')
            set(findall(app.Parameters2Panel, '-property', 'enable'), 'enable', 'off')
            buttonsandlabelsToEnable=[app.RTIbox, app.RTIbox_2, app.RTIbox_3, app.RTIbox_4, app.RTIbox_5, app.RTIbox_6, ...
                app.ShowRTIcurvesLabel, app.TLabel, app.Units_3, app.rad, app.MPlabel, app.mLabel, ...
                app.StartAnalysis, app.CLLabel_4, app.Clim1_4, app.Clim2_4, app.UnitsC_4, app.FitBox];
            set(buttonsandlabelsToEnable,'Enable', 'on');
            app.Analysis=2;
            cla(app.AnalysisPlot)
            
        end

        % Button pushed function: CompareModels
        function CompareModelsButtonPushed(app, event)
            set(app.Tensorpannel,'visible', 'off')
            pannelsToEnable=[app.Parameters1Panel, app.Parameters2Panel];
            buttonsandlabelsToEnable=[app.RTIbox, app.RTIbox_2, app.RTIbox_3, app.RTIbox_4, app.RTIbox_5, app.RTIbox_6, ...
                app.ShowRTIcurvesLabel, app.TLabel, app.Units_3, app.rad, app.MPlabel, app.mLabel, ...
                app.StartAnalysis, app.CLLabel_4, app.Clim1_4, app.Clim2_4, app.UnitsC_4];
            set(buttonsandlabelsToEnable,'Enable', 'on');
            set(findall(pannelsToEnable, '-property', 'enable'), 'enable', 'on');
            app.Analysis=3;
            cla(app.AnalysisPlot)
        end

        % Button pushed function: CompareMolec
        function CompareMolecButtonPushed(app, event)
            set(app.Tensorpannel,'visible', 'off')
            pannelsToEnable=[app.Parameters1Panel, app.Parameters2Panel];
            buttonsandlabelsToEnable=[app.RTIbox, app.RTIbox_2, app.RTIbox_3, app.RTIbox_4, app.RTIbox_5, app.RTIbox_6, ...
                app.ShowRTIcurvesLabel, app.TLabel, app.Units_3, app.rad, app.MPlabel, app.mLabel, ...
                app.StartAnalysis, app.CLLabel_4, app.Clim1_4, app.Clim2_4, app.UnitsC_4];
            set(buttonsandlabelsToEnable,'Enable', 'on');
            set(findall(pannelsToEnable, '-property', 'enable'), 'enable', 'on');
            app.Analysis=4;
            cla(app.AnalysisPlot)
        end

        % Callback function
        function YLim1ValueChanged(app, event)
            min = app.YLim1.Value;
            max = app.YLim2.Value;
            app.AnalysisPlot.YLim(1)=min;
            app.AnalysisPlot.YLim(2)=max;
        end

        % Button pushed function: Save_2
        function Save_2ButtonPushed(app, event)
             FN=[app.resfolder,app.FileName_4.Value];
            savefig(app.fig1,FN);
        end

        % Value changed function: Dif
        function DifValueChanged(app, event)
            value = app.Dif.Value;
            if value==12.5
                app.Maxnump.Value=4.67*10^10; %Maximum number of particles of TMA per um3
            elseif value==0.83
                app.Maxnump.Value=1.37*10^7;
            elseif value==2.3
                app.Maxnump.Value=2.94*10^8;    
            elseif value==0.38
                app.Maxnump.Value=1.32*10^6;
            elseif value==7.6
                app.Maxnump.Value=1.08*10^10;
            end
        end

        % Value changed function: Dif_2
        function Dif_2ValueChanged(app, event)
            value = app.Dif_2.Value;
            if value==12.5
                app.Maxnump2.Value=4.67*10^10; %Maximum number of particles of TMA per um3
            elseif value==0.83
                app.Maxnump2.Value=1.37*10^7;
            elseif value==2.3
                app.Maxnump.Value=2.94*10^8; 
            elseif value==0.38
                app.Maxnump2.Value=1.32*10^6;
            elseif value==7.6
                app.Maxnump2.Value=1.08*10^10;
            end
        end

        % Button pushed function: LoadButton_3
        function LoadButton_3Pushed(app, event)
            [alpha,lamda]=ImageAnalysis(app);
            app.Alpha_3.Value=alpha;
            app.Lamda_3.Value=lamda;
        end

        % Button pushed function: Cprofile
        function CprofileButtonPushed(app, event)
            set(app.Tensorpannel,'visible', 'off')
            set(findall(app.Parameters1Panel, '-property', 'enable'), 'enable', 'on')
            set(findall(app.Parameters2Panel, '-property', 'enable'), 'enable', 'off')
            buttonsandlabelsToEnable=[app.TLabel, app.Units_3, app.StartAnalysis, ...
                app.CLLabel_4, app.Clim1_4, app.Clim2_4, app.UnitsC_4, app.Cbasal_2];
            set(buttonsandlabelsToEnable,'Enable', 'on');           
            app.Analysis=5;
            cla(app.AnalysisPlot)
        end

        % Button pushed function: CoeffVar
        function CoeffVarButtonPushed(app, event)
           set(app.Tensorpannel,'visible', 'off')
            set(findall(app.Parameters1Panel, '-property', 'enable'), 'enable', 'on')
            set(findall(app.Parameters2Panel, '-property', 'enable'), 'enable', 'off')
            buttonsandlabelsToEnable=[app.TLabel, app.Units_3, app.StartAnalysis, ...
                app.CLLabel_4, app.Clim1_4, app.Clim2_4, app.UnitsC_4, app.Cbasal_2];
            set(buttonsandlabelsToEnable,'Enable', 'on');             
            app.Analysis=6;
            cla(app.AnalysisPlot) 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DiffusionModel = uifigure('Visible', 'off');
            app.DiffusionModel.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.DiffusionModel);
            app.OneSimulationTab = uitab(app.TabGroup);
            app.OneSimulationTab.Title = 'One Simulation';
            app.LoadImagePanel = uipanel(app.OneSimulationTab);
            app.LoadImagePanel.Title = 'Load Image';
            app.dxmLabel = uilabel(app.LoadImagePanel);
            app.dxmLabel.Text = 'dx (μm)';
            app.dx = uieditfield(app.LoadImagePanel, 'numeric');
            app.dx.Limits = [0 Inf];
            app.dx.Value = 7.5;
            app.ImageDropDownLabel = uilabel(app.LoadImagePanel);
            app.ImageDropDownLabel.Text = 'Image';
            app.ImageDropDown = uidropdown(app.LoadImagePanel);
            app.MaskDropDownLabel = uilabel(app.LoadImagePanel);
            app.MaskDropDownLabel.Text = 'Mask';
            app.MaskDropDown = uidropdown(app.LoadImagePanel);
            app.dymLabel = uilabel(app.LoadImagePanel);
            app.dymLabel.Text = 'dy (μm)';
            app.dy = uieditfield(app.LoadImagePanel, 'numeric');
            app.dy.Limits = [0 Inf];
            app.dy.Value = 7.5;
            app.dzmLabel = uilabel(app.LoadImagePanel);
            app.dzmLabel.Text = 'dz (μm)';
            app.dz = uieditfield(app.LoadImagePanel, 'numeric');
            app.dz.Limits = [0 Inf];
            app.dz.Value = 75;
            app.PixelSizeLabel = uilabel(app.LoadImagePanel);
            app.PixelSizeLabel.Text = 'Pixel Size: ';
            app.ImageSizeLabel = uilabel(app.LoadImagePanel);
            app.ImageSizeLabel.Text = 'Image Size: ';
            app.MzEditFieldLabel = uilabel(app.LoadImagePanel);
            app.MzEditFieldLabel.Text = 'Mz';
            app.Mz = uieditfield(app.LoadImagePanel, 'numeric');
            app.Mz.Limits = [1 Inf];
            app.Mz.Value = 10;
            app.MyEditFieldLabel = uilabel(app.LoadImagePanel);
            app.MyEditFieldLabel.Text = 'My';
            app.My = uieditfield(app.LoadImagePanel, 'numeric');
            app.My.Limits = [1 Inf];
            app.My.Value = 100;
            app.MxEditFieldLabel = uilabel(app.LoadImagePanel);
            app.MxEditFieldLabel.Text = 'Mx ';
            app.Mx = uieditfield(app.LoadImagePanel, 'numeric');
            app.Mx.Limits = [1 Inf];
            app.Mx.Value = 100;
            app.SourcepointLabel = uilabel(app.LoadImagePanel);
            app.SourcepointLabel.Text = 'Source point:';
            app.ZEditFieldLabel = uilabel(app.LoadImagePanel);
            app.ZEditFieldLabel.Text = 'Z';
            app.Z0 = uieditfield(app.LoadImagePanel, 'numeric');
            app.Z0.Limits = [1 Inf];
            app.Z0.Value = 5;
            app.YEditFieldLabel = uilabel(app.LoadImagePanel);
            app.YEditFieldLabel.Text = 'Y';
            app.Y0 = uieditfield(app.LoadImagePanel, 'numeric');
            app.Y0.Limits = [1 Inf];
            app.Y0.Value = 50;
            app.XEditFieldLabel = uilabel(app.LoadImagePanel);
            app.XEditFieldLabel.Text = 'X';
            app.X0 = uieditfield(app.LoadImagePanel, 'numeric');
            app.X0.Limits = [1 Inf];
            app.X0.Value = 50;
            app.InitialPixelLabel = uilabel(app.LoadImagePanel);
            app.InitialPixelLabel.Text = 'Initial Pixel:';
            app.YEditField_2Label = uilabel(app.LoadImagePanel);
            app.YEditField_2Label.Text = 'Y';
            app.idx1 = uieditfield(app.LoadImagePanel, 'numeric');
            app.idx1.Limits = [1 Inf];
            app.idx1.Value = 1;
            app.XEditField_2Label = uilabel(app.LoadImagePanel);
            app.XEditField_2Label.Text = 'X';
            app.idx2 = uieditfield(app.LoadImagePanel, 'numeric');
            app.idx2.Limits = [1 Inf];
            app.idx2.Value = 1;
            app.coment = uilabel(app.LoadImagePanel);
            app.coment.Text = 'To choose a ROI within an image';
            app.LoadButton = uibutton(app.LoadImagePanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Icon = 'load.png';
            app.LoadButton.Text = 'Load';
            app.CorrectioncoeffforblurredimagesLabel = uilabel(app.LoadImagePanel);
            app.CorrectioncoeffforblurredimagesLabel.Text = 'Correction coeff for blurred images';
            app.Aux = uieditfield(app.LoadImagePanel, 'numeric');
            app.Aux.Limits = [0 1];
            app.Aux.Value = 1;
            app.SimPanel = uipanel(app.OneSimulationTab);
            app.SimPanel.Title = 'Simulation Parameters';
            app.ModelDropDownLabel = uilabel(app.SimPanel);
            app.ModelDropDownLabel.Text = 'Model';
            app.ModelDropDown = uidropdown(app.SimPanel);
            app.ModelDropDown.Items = {'2D', 'pseudo 3D', '3D', '2D multiple release', ''};
            app.ModelDropDown.Value = '3D';
            app.TimeLabel = uilabel(app.SimPanel);
            app.TimeLabel.Text = 'Time :';
            app.SourcedurationEditFieldLabel = uilabel(app.SimPanel);
            app.SourcedurationEditFieldLabel.Text = 'Source duration';
            app.Sourceduration = uieditfield(app.SimPanel, 'numeric');
            app.Sourceduration.Limits = [0 Inf];
            app.Sourceduration.Value = 50;
            app.TimestepEditFieldLabel = uilabel(app.SimPanel);
            app.TimestepEditFieldLabel.Text = 'Time step';
            app.Timestep = uieditfield(app.SimPanel, 'numeric');
            app.Timestep.Limits = [0 Inf];
            app.Timestep.Value = 0.001;
            app.TotalDurationEditFieldLabel = uilabel(app.SimPanel);
            app.TotalDurationEditFieldLabel.Text = 'Total Duration';
            app.TotalDuration = uieditfield(app.SimPanel, 'numeric');
            app.TotalDuration.Limits = [0 Inf];
            app.TotalDuration.Value = 150;
            app.ClearanceLabel = uilabel(app.SimPanel);
            app.ClearanceLabel.Text = 'Clearance ';
            app.Kappa = uieditfield(app.SimPanel, 'numeric');
            app.Kappa.Limits = [0 Inf];
            app.Kappa.Value = 0.095;
            app.De10m2sLabel = uilabel(app.SimPanel);
            app.De10m2sLabel.Text = '(D) e-10 m2/s ';
            app.TimeUnitsLabel = uilabel(app.SimPanel);
            app.TimeUnitsLabel.Text = 'Time Units';
            app.Units = uidropdown(app.SimPanel);
            app.Units.Items = {'s', 'ms', 'µs', ''};
            app.Units.Value = 's';
            app.FreeDifCoefficientLabel = uilabel(app.SimPanel);
            app.FreeDifCoefficientLabel.Text = 'Free Dif. Coefficient';
            app.Dif = uieditfield(app.SimPanel, 'numeric');
            app.Dif.Limits = [0 Inf];
            app.Dif.ValueChangedFcn = createCallbackFcn(app, @DifValueChanged, true);
            app.Dif.Value = 12.5;
            app.BasalConcentrationMLabel = uilabel(app.SimPanel);
            app.BasalConcentrationMLabel.Text = 'Basal Concentration (µM)';
            app.Cbasal = uieditfield(app.SimPanel, 'numeric');
            app.Cbasal.Limits = [0 Inf];
            app.BCLabel = uilabel(app.SimPanel);
            app.BCLabel.Text = 'Boundary conditions :';
            app.Boundaries = uidropdown(app.SimPanel);
            app.Boundaries.Items = {'scape', 'reapperance'};
            app.Boundaries.Value = 'scape';
            app.FrequencyHzLabel = uilabel(app.SimPanel);
            app.FrequencyHzLabel.Text = 'Frequency (Hz)';
            app.Freq = uieditfield(app.SimPanel, 'numeric');
            app.Freq.Limits = [0 Inf];
            app.Freq.Value = 1;
            app.PropertiesPanel = uipanel(app.OneSimulationTab);
            app.PropertiesPanel.Title = 'Estimated Properties';
            app.VoidvolumeLabel = uilabel(app.PropertiesPanel);
            app.VoidvolumeLabel.Text = 'Void volume (α)';
            app.Alpha = uieditfield(app.PropertiesPanel, 'numeric');
            app.Alpha.Limits = [0 Inf];
            app.Alpha.Editable = 'off';
            app.Alpha.Value = 0.24;
            app.ApparentDifCoefficeintLabel = uilabel(app.PropertiesPanel);
            app.ApparentDifCoefficeintLabel.Text = 'Apparent Dif. Coefficeint ';
            app.Dmean = uieditfield(app.PropertiesPanel, 'numeric');
            app.Dmean.Limits = [0 Inf];
            app.Dmean.Editable = 'off';
            app.Dmean.Value = 0.34;
            app.TortuosityLabel = uilabel(app.PropertiesPanel);
            app.TortuosityLabel.Text = 'Tortuosity (λ)';
            app.Lamda = uieditfield(app.PropertiesPanel, 'numeric');
            app.Lamda.Limits = [0 Inf];
            app.Lamda.Editable = 'off';
            app.Lamda.Value = 1.6;
            app.e10m2sLabel = uilabel(app.PropertiesPanel);
            app.e10m2sLabel.Text = 'e-10 m2/s ';
            app.Ax2 = uiaxes(app.OneSimulationTab);
            title(app.Ax2, '')
            xlabel(app.Ax2, '')
            ylabel(app.Ax2, '')
            app.Ax2.Visible = 'off';
            app.SourcePanel = uipanel(app.OneSimulationTab);
            app.SourcePanel.Title = 'Source type';
            app.TransportnumberEditFieldLabel_3 = uilabel(app.SourcePanel);
            app.TransportnumberEditFieldLabel_3.Text = 'Transport number';
            app.TN = uieditfield(app.SourcePanel, 'numeric');
            app.TN.Limits = [0 1];
            app.TN.Value = 0.34;
            app.CurrentpAEditFieldLabel_3 = uilabel(app.SourcePanel);
            app.CurrentpAEditFieldLabel_3.Text = 'Current (pA)';
            app.Current = uieditfield(app.SourcePanel, 'numeric');
            app.Current.Limits = [0 Inf];
            app.Current.Value = 85;
            app.ISCheckBox = uicheckbox(app.SourcePanel);
            app.ISCheckBox.ValueChangedFcn = createCallbackFcn(app, @ISCheckBoxValueChanged, true);
            app.ISCheckBox.Text = ' Iontophoretic Source:';
            app.UnitVolumem3Label_2 = uilabel(app.SourcePanel);
            app.UnitVolumem3Label_2.Text = 'Unit Volume (μm3)';
            app.UV = uieditfield(app.SourcePanel, 'numeric');
            app.UV.Limits = [0 Inf];
            app.UV.Editable = 'off';
            app.UV.Value = 0.004;
            app.NofparticlesLabel_2 = uilabel(app.SourcePanel);
            app.NofparticlesLabel_2.Text = 'N of particles';
            app.Particles = uieditfield(app.SourcePanel, 'numeric');
            app.Particles.Limits = [0 Inf];
            app.Particles.Value = 200;
            app.ConcentrationuMLabel_2 = uilabel(app.SourcePanel);
            app.ConcentrationuMLabel_2.Text = 'Concentration (uM)';
            app.C0 = uieditfield(app.SourcePanel, 'numeric');
            app.C0.Limits = [0 Inf];
            app.C0.Editable = 'off';
            app.C0.Value = 0.1;
            app.PSCheckBox = uicheckbox(app.SourcePanel);
            app.PSCheckBox.ValueChangedFcn = createCallbackFcn(app, @ISCheckBoxValueChanged, true);
            app.PSCheckBox.Text = ' Particles Source:';
            app.ConcentrationuMLabel = uilabel(app.SourcePanel);
            app.ConcentrationuMLabel.Text = 'Concentration (uM)';
            app.C0_2 = uieditfield(app.SourcePanel, 'numeric');
            app.C0_2.Limits = [0 Inf];
            app.C0_2.Editable = 'off';
            app.C0_2.Value = 0.1;
            app.Maxnumberofparticlesperum3Label = uilabel(app.SourcePanel);
            app.Maxnumberofparticlesperum3Label.Text = 'Max number of particles per um3';
            app.Maxnump = uieditfield(app.SourcePanel, 'numeric');
            app.Maxnump.Limits = [0 Inf];
            app.Maxnump.Value = 200;
            app.SavePanel = uipanel(app.OneSimulationTab);
            app.SavePanel.Title = 'Save options';
            app.FileNameLabel_3 = uilabel(app.SavePanel);
            app.FileNameLabel_3.Text = 'File Name:';
            app.FileName = uieditfield(app.SavePanel, 'text');
            app.FileName.Value = '20201019_01';
            app.SaverateLabel = uilabel(app.SavePanel);
            app.SaverateLabel.Text = 'Save rate';
            app.SaveRate = uieditfield(app.SavePanel, 'numeric');
            app.SaveRate.Limits = [1 Inf];
            app.SaveRate.Value = 1;
            app.coment_3 = uilabel(app.SavePanel);
            app.coment_3.Text = 'Rate at wich time points are saved';
            app.StartButton = uibutton(app.OneSimulationTab, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start ';
            app.VideoPanel = uipanel(app.OneSimulationTab);
            app.VideoPanel.Title = 'Video options';
            app.coment_4 = uilabel(app.VideoPanel);
            app.coment_4.Text = 'for display';
            app.ConcentrationlimitsLabel = uilabel(app.VideoPanel);
            app.ConcentrationlimitsLabel.Text = 'Concentration limits:';
            app.toLabel_2 = uilabel(app.VideoPanel);
            app.toLabel_2.Text = 'to';
            app.Clim2 = uieditfield(app.VideoPanel, 'numeric');
            app.Clim2.Limits = [0 Inf];
            app.Clim2.Value = 1;
            app.fromLabel_2 = uilabel(app.VideoPanel);
            app.fromLabel_2.Text = 'from';
            app.Clim1 = uieditfield(app.VideoPanel, 'numeric');
            app.Clim1.Limits = [0 Inf];
            app.Clim1.Value = 0.001;
            app.playButton = uibutton(app.VideoPanel, 'push');
            app.playButton.ButtonPushedFcn = createCallbackFcn(app, @playButtonPushed, true);
            app.playButton.Icon = 'play.png';
            app.playButton.Text = '';
            app.UnitsDropDownLabel_3 = uilabel(app.VideoPanel);
            app.UnitsDropDownLabel_3.Text = 'Units';
            app.UnitsC = uidropdown(app.VideoPanel);
            app.UnitsC.Items = {'mM', 'µM', 'nM'};
            app.UnitsC.Value = 'mM';
            app.FramespersecondLabel = uilabel(app.VideoPanel);
            app.FramespersecondLabel.Text = 'Frames per second:';
            app.fps = uieditfield(app.VideoPanel, 'numeric');
            app.fps.Limits = [0 Inf];
            app.fps.Value = 10;
            app.FileNameLabel_7 = uilabel(app.VideoPanel);
            app.FileNameLabel_7.Text = 'File Name:';
            app.FileName_6 = uieditfield(app.VideoPanel, 'text');
            app.FileName_6.Value = '20201019_01';
            app.MultipleSimulationsTab = uitab(app.TabGroup);
            app.MultipleSimulationsTab.Title = 'Multiple Simulations';
            app.PropertiesPanel_2 = uipanel(app.MultipleSimulationsTab);
            app.PropertiesPanel_2.Title = 'Estimated Properties';
            app.VoidvolumeLabel_2 = uilabel(app.PropertiesPanel_2);
            app.VoidvolumeLabel_2.Text = 'Void volume (α)';
            app.Alpha_2 = uieditfield(app.PropertiesPanel_2, 'numeric');
            app.Alpha_2.Limits = [0 Inf];
            app.Alpha_2.Editable = 'off';
            app.Alpha_2.Value = 0.24;
            app.DiffusionCoefficeintDLabel_2 = uilabel(app.PropertiesPanel_2);
            app.DiffusionCoefficeintDLabel_2.Text = 'Diffusion Coefficeint (D)';
            app.Dmean_2 = uieditfield(app.PropertiesPanel_2, 'numeric');
            app.Dmean_2.Limits = [0 Inf];
            app.Dmean_2.Editable = 'off';
            app.Dmean_2.Value = 0.34;
            app.TortuosityLabel_2 = uilabel(app.PropertiesPanel_2);
            app.TortuosityLabel_2.Text = 'Tortuosity (λ)';
            app.Lamda_2 = uieditfield(app.PropertiesPanel_2, 'numeric');
            app.Lamda_2.Limits = [0 Inf];
            app.Lamda_2.Editable = 'off';
            app.Lamda_2.Value = 1.6;
            app.e10m2sLabel_2 = uilabel(app.PropertiesPanel_2);
            app.e10m2sLabel_2.Text = 'e-10 m2/s ';
            app.LoadImagePanel_2 = uipanel(app.MultipleSimulationsTab);
            app.LoadImagePanel_2.Title = 'Load Image';
            app.dxmLabel_2 = uilabel(app.LoadImagePanel_2);
            app.dxmLabel_2.Text = 'dx (μm)';
            app.dx_2 = uieditfield(app.LoadImagePanel_2, 'numeric');
            app.dx_2.Limits = [0 Inf];
            app.dx_2.Value = 7.5;
            app.dymLabel_2 = uilabel(app.LoadImagePanel_2);
            app.dymLabel_2.Text = 'dy (μm)';
            app.dy_2 = uieditfield(app.LoadImagePanel_2, 'numeric');
            app.dy_2.Limits = [0 Inf];
            app.dy_2.Value = 7.5;
            app.dzmLabel_2 = uilabel(app.LoadImagePanel_2);
            app.dzmLabel_2.Text = 'dz (μm)';
            app.dz_2 = uieditfield(app.LoadImagePanel_2, 'numeric');
            app.dz_2.Limits = [0 Inf];
            app.dz_2.Value = 75;
            app.ImageDropDown_2Label = uilabel(app.LoadImagePanel_2);
            app.ImageDropDown_2Label.Text = 'Image';
            app.ImageDropDown_2 = uidropdown(app.LoadImagePanel_2);
            app.MaskDropDown_2Label = uilabel(app.LoadImagePanel_2);
            app.MaskDropDown_2Label.Text = 'Mask';
            app.MaskDropDown_2 = uidropdown(app.LoadImagePanel_2);
            app.PixelSizeLabel_2 = uilabel(app.LoadImagePanel_2);
            app.PixelSizeLabel_2.Text = 'Pixel Size: ';
            app.ImageSizeLabel_2 = uilabel(app.LoadImagePanel_2);
            app.ImageSizeLabel_2.Text = 'Image Size: ';
            app.MzEditFieldLabel_2 = uilabel(app.LoadImagePanel_2);
            app.MzEditFieldLabel_2.Text = 'Mz';
            app.Mz_2 = uieditfield(app.LoadImagePanel_2, 'numeric');
            app.Mz_2.Limits = [1 Inf];
            app.Mz_2.Value = 10;
            app.MyEditFieldLabel_2 = uilabel(app.LoadImagePanel_2);
            app.MyEditFieldLabel_2.Text = 'My';
            app.My_2 = uieditfield(app.LoadImagePanel_2, 'numeric');
            app.My_2.Limits = [1 Inf];
            app.My_2.Value = 100;
            app.MxEditFieldLabel_2 = uilabel(app.LoadImagePanel_2);
            app.MxEditFieldLabel_2.Text = 'Mx ';
            app.Mx_2 = uieditfield(app.LoadImagePanel_2, 'numeric');
            app.Mx_2.Limits = [1 Inf];
            app.Mx_2.Value = 100;
            app.IndexesLabel = uilabel(app.LoadImagePanel_2);
            app.IndexesLabel.Text = 'Indexes:';
            app.YEditField_2Label_2 = uilabel(app.LoadImagePanel_2);
            app.YEditField_2Label_2.Text = 'Y';
            app.Y0_2 = uieditfield(app.LoadImagePanel_2, 'numeric');
            app.Y0_2.Limits = [1 Inf];
            app.Y0_2.Value = 1;
            app.XEditField_2Label_2 = uilabel(app.LoadImagePanel_2);
            app.XEditField_2Label_2.Text = 'X';
            app.X0_2 = uieditfield(app.LoadImagePanel_2, 'numeric');
            app.X0_2.Limits = [1 Inf];
            app.X0_2.Value = 1;
            app.coment_2 = uilabel(app.LoadImagePanel_2);
            app.coment_2.Text = 'To choose a ROI within an image';
            app.LoadButton_2 = uibutton(app.LoadImagePanel_2, 'push');
            app.LoadButton_2.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton_2.Icon = 'load.png';
            app.LoadButton_2.Text = 'Load';
            app.SourceFileLabel = uilabel(app.LoadImagePanel_2);
            app.SourceFileLabel.Text = 'Source File: ';
            app.SourceDropDown = uidropdown(app.LoadImagePanel_2);
            app.toLabel = uilabel(app.LoadImagePanel_2);
            app.toLabel.Text = 'to';
            app.idxend = uieditfield(app.LoadImagePanel_2, 'numeric');
            app.idxend.Limits = [1 Inf];
            app.idxend.Value = 100;
            app.fromLabel = uilabel(app.LoadImagePanel_2);
            app.fromLabel.Text = 'from';
            app.idx0 = uieditfield(app.LoadImagePanel_2, 'numeric');
            app.idx0.Limits = [1 Inf];
            app.idx0.Value = 1;
            app.ZEditFieldLabel_2 = uilabel(app.LoadImagePanel_2);
            app.ZEditFieldLabel_2.Text = 'Z';
            app.Z0_2 = uieditfield(app.LoadImagePanel_2, 'numeric');
            app.Z0_2.Limits = [1 Inf];
            app.Z0_2.Value = 5;
            app.SimPanel_2 = uipanel(app.MultipleSimulationsTab);
            app.SimPanel_2.Title = 'Simulation Parameters';
            app.ModelDropDown_2Label = uilabel(app.SimPanel_2);
            app.ModelDropDown_2Label.Text = 'Model';
            app.ModelDropDown_2 = uidropdown(app.SimPanel_2);
            app.ModelDropDown_2.Items = {'2D', 'pseudo 3D', '3D', ''};
            app.ModelDropDown_2.Value = '3D';
            app.ClearanceLabel_2 = uilabel(app.SimPanel_2);
            app.ClearanceLabel_2.Text = 'Clearance ';
            app.Kappa_2 = uieditfield(app.SimPanel_2, 'numeric');
            app.Kappa_2.Limits = [0 Inf];
            app.Kappa_2.Value = 0.095;
            app.TimeLabel_2 = uilabel(app.SimPanel_2);
            app.TimeLabel_2.Text = 'Time :';
            app.SourcedurationEditFieldLabel_2 = uilabel(app.SimPanel_2);
            app.SourcedurationEditFieldLabel_2.Text = 'Source duration';
            app.Sourceduration_2 = uieditfield(app.SimPanel_2, 'numeric');
            app.Sourceduration_2.Limits = [0 Inf];
            app.Sourceduration_2.Value = 50;
            app.TimestepEditFieldLabel_2 = uilabel(app.SimPanel_2);
            app.TimestepEditFieldLabel_2.Text = 'Time step';
            app.Timestep_2 = uieditfield(app.SimPanel_2, 'numeric');
            app.Timestep_2.Limits = [0 Inf];
            app.Timestep_2.Value = 0.001;
            app.TotalDurationEditFieldLabel_2 = uilabel(app.SimPanel_2);
            app.TotalDurationEditFieldLabel_2.Text = 'Total Duration';
            app.TotalDuration_2 = uieditfield(app.SimPanel_2, 'numeric');
            app.TotalDuration_2.Limits = [0 Inf];
            app.TotalDuration_2.Value = 150;
            app.FileNameLabel_2 = uilabel(app.SimPanel_2);
            app.FileNameLabel_2.Text = 'File Name:';
            app.FileName_2 = uieditfield(app.SimPanel_2, 'text');
            app.FileName_2.Value = '20201019';
            app.UnitsDropDownLabel_2 = uilabel(app.SimPanel_2);
            app.UnitsDropDownLabel_2.Text = 'Units';
            app.Units_2 = uidropdown(app.SimPanel_2);
            app.Units_2.Items = {'s', 'ms', 'µs', ''};
            app.Units_2.Value = 's';
            app.SaverateLabel_2 = uilabel(app.SimPanel_2);
            app.SaverateLabel_2.Text = 'Save rate';
            app.SaveRate_2 = uieditfield(app.SimPanel_2, 'numeric');
            app.SaveRate_2.Limits = [1 Inf];
            app.SaveRate_2.Value = 1;
            app.coment_5 = uilabel(app.SimPanel_2);
            app.coment_5.Text = 'Rate at wich time points are saved';
            app.De10m2sLabel_2 = uilabel(app.SimPanel_2);
            app.De10m2sLabel_2.Text = '(D) e-10 m2/s ';
            app.FreeDifCoefficientLabel_2 = uilabel(app.SimPanel_2);
            app.FreeDifCoefficientLabel_2.Text = 'Free Dif. Coefficient';
            app.Dif_2 = uieditfield(app.SimPanel_2, 'numeric');
            app.Dif_2.Limits = [0 Inf];
            app.Dif_2.ValueChangedFcn = createCallbackFcn(app, @Dif_2ValueChanged, true);
            app.Dif_2.Value = 12.5;
            app.UnitVolumem3Label_4 = uilabel(app.SimPanel_2);
            app.UnitVolumem3Label_4.Text = 'Unit Volume (μm3)';
            app.UV_3 = uieditfield(app.SimPanel_2, 'numeric');
            app.UV_3.Limits = [0 1];
            app.UV_3.Editable = 'off';
            app.UV_3.Value = 0.004;
            app.NofparticlesLabel_4 = uilabel(app.SimPanel_2);
            app.NofparticlesLabel_4.Text = 'N of particles';
            app.Particles_3 = uieditfield(app.SimPanel_2, 'numeric');
            app.Particles_3.Limits = [0 Inf];
            app.Particles_3.ValueChangedFcn = createCallbackFcn(app, @Particles_3ValueChanged, true);
            app.Particles_3.Value = 200;
            app.ConcentrationMLabel = uilabel(app.SimPanel_2);
            app.ConcentrationMLabel.Text = 'Concentration (µM)';
            app.C0_4 = uieditfield(app.SimPanel_2, 'numeric');
            app.C0_4.Limits = [0 Inf];
            app.C0_4.Editable = 'off';
            app.C0_4.Value = 0.1;
            app.ParticleSourceLabel = uilabel(app.SimPanel_2);
            app.ParticleSourceLabel.Text = {'Particle Source:'; ''};
            app.MaxNofparticlesLabel = uilabel(app.SimPanel_2);
            app.MaxNofparticlesLabel.Text = 'Max N of particles';
            app.Maxnump2 = uieditfield(app.SimPanel_2, 'numeric');
            app.Maxnump2.Limits = [0 Inf];
            app.Maxnump2.Value = 200;
            app.BCLabel_2 = uilabel(app.SimPanel_2);
            app.BCLabel_2.Text = 'Boundary conditions :';
            app.Boundaries2 = uidropdown(app.SimPanel_2);
            app.Boundaries2.Items = {'scape', 'reapperance'};
            app.Boundaries2.Value = 'scape';
            app.Ax3 = uiaxes(app.MultipleSimulationsTab);
            title(app.Ax3, '')
            xlabel(app.Ax3, '')
            ylabel(app.Ax3, '')
            app.Ax3.Visible = 'off';
            app.VideoPanel_2 = uipanel(app.MultipleSimulationsTab);
            app.VideoPanel_2.Title = 'Video options';
            app.coment_6 = uilabel(app.VideoPanel_2);
            app.coment_6.Text = 'for display';
            app.ConcentrationlimitsLabel_2 = uilabel(app.VideoPanel_2);
            app.ConcentrationlimitsLabel_2.Text = 'Concentration limits:';
            app.toLabel_4 = uilabel(app.VideoPanel_2);
            app.toLabel_4.Text = 'to';
            app.Clim2_2 = uieditfield(app.VideoPanel_2, 'numeric');
            app.Clim2_2.Limits = [0 Inf];
            app.Clim2_2.Value = 1;
            app.fromLabel_4 = uilabel(app.VideoPanel_2);
            app.fromLabel_4.Text = 'from';
            app.Clim1_2 = uieditfield(app.VideoPanel_2, 'numeric');
            app.Clim1_2.Limits = [0 Inf];
            app.Clim1_2.Value = 0.001;
            app.UnitsDropDownLabel_4 = uilabel(app.VideoPanel_2);
            app.UnitsDropDownLabel_4.Text = 'Units';
            app.UnitsC_2 = uidropdown(app.VideoPanel_2);
            app.UnitsC_2.Items = {'mM', 'µM', 'nM'};
            app.UnitsC_2.Value = 'mM';
            app.FramespersecondLabel_2 = uilabel(app.VideoPanel_2);
            app.FramespersecondLabel_2.Text = 'Frames per second:';
            app.fps_2 = uieditfield(app.VideoPanel_2, 'numeric');
            app.fps_2.Limits = [0 Inf];
            app.fps_2.Value = 10;
            app.StartButton_2 = uibutton(app.MultipleSimulationsTab, 'push');
            app.StartButton_2.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton_2.Text = 'Start ';
            app.TIFFImageTab = uitab(app.TabGroup);
            app.TIFFImageTab.Title = 'TIFF Image';
            app.SaveTIFFPanel = uipanel(app.TIFFImageTab);
            app.SaveTIFFPanel.Title = 'Save diffusion pattern as .TIFF';
            app.NumberofframesLabel = uilabel(app.SaveTIFFPanel);
            app.NumberofframesLabel.Text = 'Number of frames: ';
            app.numf = uieditfield(app.SaveTIFFPanel, 'numeric');
            app.numf.Limits = [1 Inf];
            app.numf.Value = 4;
            app.toLabel_3 = uilabel(app.SaveTIFFPanel);
            app.toLabel_3.Text = 'to';
            app.fend = uieditfield(app.SaveTIFFPanel, 'numeric');
            app.fend.Limits = [0 Inf];
            app.fend.Value = 500;
            app.fromLabel_3 = uilabel(app.SaveTIFFPanel);
            app.fromLabel_3.Text = 'from';
            app.f1 = uieditfield(app.SaveTIFFPanel, 'numeric');
            app.f1.Limits = [0 Inf];
            app.f1.Value = 1;
            app.FramelimitsLabel = uilabel(app.SaveTIFFPanel);
            app.FramelimitsLabel.Text = 'Frame limits: ';
            app.Save = uibutton(app.SaveTIFFPanel, 'push');
            app.Save.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.Save.Icon = 'save.png';
            app.Save.Text = '';
            app.FileNameLabel_4 = uilabel(app.SaveTIFFPanel);
            app.FileNameLabel_4.Text = 'File Name:';
            app.FileName_3 = uieditfield(app.SaveTIFFPanel, 'text');
            app.FileName_3.Value = '20201019_01';
            app.ModelDropDown_3Label = uilabel(app.SaveTIFFPanel);
            app.ModelDropDown_3Label.Text = 'Model';
            app.ModelDropDown_3 = uidropdown(app.SaveTIFFPanel);
            app.ModelDropDown_3.Items = {'2D', 'pseudo 3D', '3D', ''};
            app.ModelDropDown_3.Value = '3D';
            app.coment_7 = uilabel(app.SaveTIFFPanel);
            app.coment_7.Text = 'for display';
            app.ConcentrationlimitsLabel_3 = uilabel(app.SaveTIFFPanel);
            app.ConcentrationlimitsLabel_3.Text = 'Concentration limits:';
            app.toLabel_5 = uilabel(app.SaveTIFFPanel);
            app.toLabel_5.Text = 'to';
            app.Clim2_3 = uieditfield(app.SaveTIFFPanel, 'numeric');
            app.Clim2_3.Limits = [0 Inf];
            app.Clim2_3.Value = 1;
            app.fromLabel_5 = uilabel(app.SaveTIFFPanel);
            app.fromLabel_5.Text = 'from';
            app.Clim1_3 = uieditfield(app.SaveTIFFPanel, 'numeric');
            app.Clim1_3.Limits = [0 Inf];
            app.Clim1_3.Value = 0.001;
            app.UnitsDropDownLabel_5 = uilabel(app.SaveTIFFPanel);
            app.UnitsDropDownLabel_5.Text = 'Units';
            app.UnitsC_3 = uidropdown(app.SaveTIFFPanel);
            app.UnitsC_3.Items = {'mM', 'µM', 'nM'};
            app.UnitsC_3.Value = 'mM';
            app.AnalysisTab = uitab(app.TabGroup);
            app.AnalysisTab.Title = 'Analysis';
            app.TensorMap = uibutton(app.AnalysisTab, 'push');
            app.TensorMap.ButtonPushedFcn = createCallbackFcn(app, @TensorMapButtonPushed, true);
            app.TensorMap.Text = 'Tensor Map';
            app.ChooseAnalysisLabel = uilabel(app.AnalysisTab);
            app.ChooseAnalysisLabel.Text = 'Choose Analysis: ';
            app.DiffusionCurves = uibutton(app.AnalysisTab, 'push');
            app.DiffusionCurves.ButtonPushedFcn = createCallbackFcn(app, @DiffusionCurvesButtonPushed, true);
            app.DiffusionCurves.Text = 'Diffusion Curves';
            app.CompareModels = uibutton(app.AnalysisTab, 'push');
            app.CompareModels.ButtonPushedFcn = createCallbackFcn(app, @CompareModelsButtonPushed, true);
            app.CompareModels.Text = 'Compare Models';
            app.CompareMolec = uibutton(app.AnalysisTab, 'push');
            app.CompareMolec.ButtonPushedFcn = createCallbackFcn(app, @CompareMolecButtonPushed, true);
            app.CompareMolec.Text = 'Compare Molecules';
            app.DiffusionCurves_4 = uibutton(app.AnalysisTab, 'push');
            app.DiffusionCurves_4.Text = 'Tau vs distance';
            app.DiffusionCurves_5 = uibutton(app.AnalysisTab, 'push');
            app.DiffusionCurves_5.Text = '[C] vs distance';
            app.CoeffVar = uibutton(app.AnalysisTab, 'push');
            app.CoeffVar.ButtonPushedFcn = createCallbackFcn(app, @CoeffVarButtonPushed, true);
            app.CoeffVar.Text = 'COV vs distance';
            app.Parameters1Panel = uipanel(app.AnalysisTab);
            app.Parameters1Panel.Title = 'Parameters 1';
            app.FileNameLabel_5 = uilabel(app.Parameters1Panel);
            app.FileNameLabel_5.Text = 'File Name:';
            app.FileName_4 = uieditfield(app.Parameters1Panel, 'text');
            app.FileName_4.Value = '20201019_01';
            app.PixelSizeLabel_3 = uilabel(app.Parameters1Panel);
            app.PixelSizeLabel_3.Text = 'Pixel Size: ';
            app.dxmLabel_3 = uilabel(app.Parameters1Panel);
            app.dxmLabel_3.Text = 'dx (μm)';
            app.dx_3 = uieditfield(app.Parameters1Panel, 'numeric');
            app.dx_3.Limits = [0 Inf];
            app.dx_3.Value = 7.5;
            app.dymLabel_3 = uilabel(app.Parameters1Panel);
            app.dymLabel_3.Text = 'dy (μm)';
            app.dy_3 = uieditfield(app.Parameters1Panel, 'numeric');
            app.dy_3.Limits = [0 Inf];
            app.dy_3.Value = 7.5;
            app.dzmLabel_3 = uilabel(app.Parameters1Panel);
            app.dzmLabel_3.Text = 'dz (μm)';
            app.dz_3 = uieditfield(app.Parameters1Panel, 'numeric');
            app.dz_3.Limits = [0 Inf];
            app.dz_3.Value = 75;
            app.ImageSizeLabel_3 = uilabel(app.Parameters1Panel);
            app.ImageSizeLabel_3.Text = 'Image Size: ';
            app.MzEditFieldLabel_3 = uilabel(app.Parameters1Panel);
            app.MzEditFieldLabel_3.Text = 'Mz';
            app.Mz_3 = uieditfield(app.Parameters1Panel, 'numeric');
            app.Mz_3.Limits = [1 Inf];
            app.Mz_3.Value = 10;
            app.MyEditFieldLabel_3 = uilabel(app.Parameters1Panel);
            app.MyEditFieldLabel_3.Text = 'My';
            app.My_3 = uieditfield(app.Parameters1Panel, 'numeric');
            app.My_3.Limits = [1 Inf];
            app.My_3.Value = 100;
            app.MxEditFieldLabel_3 = uilabel(app.Parameters1Panel);
            app.MxEditFieldLabel_3.Text = 'Mx ';
            app.Mx_3 = uieditfield(app.Parameters1Panel, 'numeric');
            app.Mx_3.Limits = [1 Inf];
            app.Mx_3.Value = 100;
            app.SourcepointLabel_2 = uilabel(app.Parameters1Panel);
            app.SourcepointLabel_2.Text = 'Source point:';
            app.ZEditFieldLabel_3 = uilabel(app.Parameters1Panel);
            app.ZEditFieldLabel_3.Text = 'Z';
            app.Z0_3 = uieditfield(app.Parameters1Panel, 'numeric');
            app.Z0_3.Limits = [1 Inf];
            app.Z0_3.Value = 5;
            app.YEditFieldLabel_2 = uilabel(app.Parameters1Panel);
            app.YEditFieldLabel_2.Text = 'Y';
            app.Y0_3 = uieditfield(app.Parameters1Panel, 'numeric');
            app.Y0_3.Limits = [1 Inf];
            app.Y0_3.Value = 50;
            app.XEditFieldLabel_2 = uilabel(app.Parameters1Panel);
            app.XEditFieldLabel_2.Text = 'X';
            app.X0_3 = uieditfield(app.Parameters1Panel, 'numeric');
            app.X0_3.Limits = [1 Inf];
            app.X0_3.Value = 50;
            app.MoleculeLabel = uilabel(app.Parameters1Panel);
            app.MoleculeLabel.Text = 'Molecule:';
            app.Molec = uieditfield(app.Parameters1Panel, 'text');
            app.Molec.Value = 'TMA';
            app.ModelLabel = uilabel(app.Parameters1Panel);
            app.ModelLabel.Text = 'Model: ';
            app.Model = uieditfield(app.Parameters1Panel, 'text');
            app.Model.Value = '3D';
            app.InitialPixelLabel_2 = uilabel(app.Parameters1Panel);
            app.InitialPixelLabel_2.Text = 'Initial Pixel:';
            app.YEditField_2Label_3 = uilabel(app.Parameters1Panel);
            app.YEditField_2Label_3.Text = 'Y';
            app.idx1_2 = uieditfield(app.Parameters1Panel, 'numeric');
            app.idx1_2.Limits = [1 Inf];
            app.idx1_2.Value = 1;
            app.XEditField_2Label_3 = uilabel(app.Parameters1Panel);
            app.XEditField_2Label_3.Text = 'X';
            app.idx2_2 = uieditfield(app.Parameters1Panel, 'numeric');
            app.idx2_2.Limits = [1 Inf];
            app.idx2_2.Value = 1;
            app.ImageDropDown_5Label = uilabel(app.Parameters1Panel);
            app.ImageDropDown_5Label.Text = 'Image';
            app.ImageDropDown_5 = uidropdown(app.Parameters1Panel);
            app.varianceframesizeLabel = uilabel(app.Parameters1Panel);
            app.varianceframesizeLabel.Text = 'variance frame size ';
            app.var_size = uieditfield(app.Parameters1Panel, 'numeric');
            app.var_size.Limits = [2 Inf];
            app.var_size.RoundFractionalValues = 'on';
            app.var_size.Value = 10;
            app.AnalysisPlot = uiaxes(app.AnalysisTab);
            title(app.AnalysisPlot, '')
            xlabel(app.AnalysisPlot, 'X')
            ylabel(app.AnalysisPlot, 'Y')
            app.Parameters2Panel = uipanel(app.AnalysisTab);
            app.Parameters2Panel.Title = 'Parameters 2';
            app.FileNameLabel_6 = uilabel(app.Parameters2Panel);
            app.FileNameLabel_6.Text = 'File Name:';
            app.FileName_5 = uieditfield(app.Parameters2Panel, 'text');
            app.FileName_5.Value = '20201019_01';
            app.PixelSizeLabel_4 = uilabel(app.Parameters2Panel);
            app.PixelSizeLabel_4.Text = 'Pixel Size: ';
            app.dxmLabel_4 = uilabel(app.Parameters2Panel);
            app.dxmLabel_4.Text = 'dx (μm)';
            app.dx_4 = uieditfield(app.Parameters2Panel, 'numeric');
            app.dx_4.Limits = [0 Inf];
            app.dx_4.Value = 7.5;
            app.dymLabel_4 = uilabel(app.Parameters2Panel);
            app.dymLabel_4.Text = 'dy (μm)';
            app.dy_4 = uieditfield(app.Parameters2Panel, 'numeric');
            app.dy_4.Limits = [0 Inf];
            app.dy_4.Value = 7.5;
            app.dzmLabel_4 = uilabel(app.Parameters2Panel);
            app.dzmLabel_4.Text = 'dz (μm)';
            app.dz_4 = uieditfield(app.Parameters2Panel, 'numeric');
            app.dz_4.Limits = [0 Inf];
            app.dz_4.Value = 75;
            app.ImageSizeLabel_4 = uilabel(app.Parameters2Panel);
            app.ImageSizeLabel_4.Text = 'Image Size: ';
            app.MzEditFieldLabel_4 = uilabel(app.Parameters2Panel);
            app.MzEditFieldLabel_4.Text = 'Mz';
            app.Mz_4 = uieditfield(app.Parameters2Panel, 'numeric');
            app.Mz_4.Limits = [1 Inf];
            app.Mz_4.Value = 10;
            app.MyEditFieldLabel_4 = uilabel(app.Parameters2Panel);
            app.MyEditFieldLabel_4.Text = 'My';
            app.My_4 = uieditfield(app.Parameters2Panel, 'numeric');
            app.My_4.Limits = [1 Inf];
            app.My_4.Value = 100;
            app.MxEditFieldLabel_4 = uilabel(app.Parameters2Panel);
            app.MxEditFieldLabel_4.Text = 'Mx ';
            app.Mx_4 = uieditfield(app.Parameters2Panel, 'numeric');
            app.Mx_4.Limits = [1 Inf];
            app.Mx_4.Value = 100;
            app.SourcepointLabel_3 = uilabel(app.Parameters2Panel);
            app.SourcepointLabel_3.Text = 'Source point:';
            app.ZEditFieldLabel_4 = uilabel(app.Parameters2Panel);
            app.ZEditFieldLabel_4.Text = 'Z';
            app.Z0_4 = uieditfield(app.Parameters2Panel, 'numeric');
            app.Z0_4.Limits = [1 Inf];
            app.Z0_4.Value = 5;
            app.YEditFieldLabel_3 = uilabel(app.Parameters2Panel);
            app.YEditFieldLabel_3.Text = 'Y';
            app.Y0_4 = uieditfield(app.Parameters2Panel, 'numeric');
            app.Y0_4.Limits = [1 Inf];
            app.Y0_4.Value = 50;
            app.XEditFieldLabel_3 = uilabel(app.Parameters2Panel);
            app.XEditFieldLabel_3.Text = 'X';
            app.X0_4 = uieditfield(app.Parameters2Panel, 'numeric');
            app.X0_4.Limits = [1 Inf];
            app.X0_4.Value = 50;
            app.MoleculeLabel_2 = uilabel(app.Parameters2Panel);
            app.MoleculeLabel_2.Text = 'Molecule:';
            app.Molec_2 = uieditfield(app.Parameters2Panel, 'text');
            app.Molec_2.Value = 'TMA';
            app.ModelLabel_2 = uilabel(app.Parameters2Panel);
            app.ModelLabel_2.Text = 'Model: ';
            app.Model_2 = uieditfield(app.Parameters2Panel, 'text');
            app.Model_2.Value = '3D';
            app.InitialPixelLabel_3 = uilabel(app.Parameters2Panel);
            app.InitialPixelLabel_3.Text = 'Initial Pixel:';
            app.YEditField_2Label_4 = uilabel(app.Parameters2Panel);
            app.YEditField_2Label_4.Text = 'Y';
            app.idx1_3 = uieditfield(app.Parameters2Panel, 'numeric');
            app.idx1_3.Limits = [1 Inf];
            app.idx1_3.Value = 1;
            app.XEditField_2Label_4 = uilabel(app.Parameters2Panel);
            app.XEditField_2Label_4.Text = 'X';
            app.idx2_3 = uieditfield(app.Parameters2Panel, 'numeric');
            app.idx2_3.Limits = [1 Inf];
            app.idx2_3.Value = 1;
            app.StartAnalysis = uibutton(app.AnalysisTab, 'push');
            app.StartAnalysis.ButtonPushedFcn = createCallbackFcn(app, @StartAnalysisButtonPushed, true);
            app.StartAnalysis.Text = 'Start ';
            app.CLLabel_4 = uilabel(app.AnalysisTab);
            app.CLLabel_4.Text = 'Concentration limits:';
            app.toLabel_6 = uilabel(app.AnalysisTab);
            app.toLabel_6.Text = 'to';
            app.Clim2_4 = uieditfield(app.AnalysisTab, 'numeric');
            app.Clim2_4.Limits = [0 Inf];
            app.Clim2_4.Value = 1;
            app.fromLabel_6 = uilabel(app.AnalysisTab);
            app.fromLabel_6.Text = 'from';
            app.Clim1_4 = uieditfield(app.AnalysisTab, 'numeric');
            app.Clim1_4.Limits = [0 Inf];
            app.Clim1_4.Value = 0.001;
            app.UnitsDropDownLabel_8 = uilabel(app.AnalysisTab);
            app.UnitsDropDownLabel_8.Text = 'Units';
            app.UnitsC_4 = uidropdown(app.AnalysisTab);
            app.UnitsC_4.Items = {'M', 'mM', 'µM', 'nM'};
            app.UnitsC_4.Value = 'mM';
            app.Tensorpannel = uipanel(app.AnalysisTab);
            app.Tensorpannel.Title = 'Tensor Map properties';
            app.ImageDropDown_3Label = uilabel(app.Tensorpannel);
            app.ImageDropDown_3Label.Text = 'Image';
            app.ImageDropDown_3 = uidropdown(app.Tensorpannel);
            app.TensorMapLabel = uilabel(app.Tensorpannel);
            app.TensorMapLabel.Text = 'Tensor Map: ';
            app.MSBox = uicheckbox(app.Tensorpannel);
            app.MSBox.Text = 'Multiple simulations';
            app.ArrowscaleLabel = uilabel(app.Tensorpannel);
            app.ArrowscaleLabel.Text = 'Arrow scale';
            app.scale = uieditfield(app.Tensorpannel, 'numeric');
            app.scale.Limits = [0 Inf];
            app.scale.Value = 3;
            app.TransparencyLabel = uilabel(app.Tensorpannel);
            app.TransparencyLabel.Text = 'Transparency :';
            app.alpha = uieditfield(app.Tensorpannel, 'numeric');
            app.alpha.Limits = [0 100];
            app.alpha.Value = 50;
            app.Label = uilabel(app.Tensorpannel);
            app.Label.Text = '%';
            app.XaxisLabel = uilabel(app.Tensorpannel);
            app.XaxisLabel.Text = 'X axis';
            app.Xs = uieditfield(app.Tensorpannel, 'numeric');
            app.Xs.Limits = [0 100];
            app.Xs.Value = 4;
            app.SquaresLabel = uilabel(app.Tensorpannel);
            app.SquaresLabel.Text = 'Squares :';
            app.YaxisLabel = uilabel(app.Tensorpannel);
            app.YaxisLabel.Text = 'Y axis';
            app.Ys = uieditfield(app.Tensorpannel, 'numeric');
            app.Ys.Limits = [0 100];
            app.Ys.Value = 4;
            app.RSQcoeficienttresholdLabel = uilabel(app.Tensorpannel);
            app.RSQcoeficienttresholdLabel.Text = 'RSQ coeficient treshold';
            app.rsq_treshold = uieditfield(app.Tensorpannel, 'numeric');
            app.rsq_treshold.Limits = [0 Inf];
            app.rsq_treshold.Value = 0.011;
            app.ShowRTIcurvesLabel = uilabel(app.AnalysisTab);
            app.ShowRTIcurvesLabel.Text = 'Show RTI curves: ';
            app.RTIbox = uicheckbox(app.AnalysisTab);
            app.RTIbox.Text = 'Agar (Hrabětová 2007)  ';
            app.RTIbox_2 = uicheckbox(app.AnalysisTab);
            app.RTIbox_2.Text = 'Brain (Hrabětová 2007)';
            app.RTIbox_3 = uicheckbox(app.AnalysisTab);
            app.RTIbox_3.Text = 'Brain range (Kress 2014)';
            app.Units_3 = uidropdown(app.AnalysisTab);
            app.Units_3.Items = {'s', 'ms', 'µs', ''};
            app.Units_3.Value = 's';
            app.TLabel = uilabel(app.AnalysisTab);
            app.TLabel.Text = 'Time scale:';
            app.MPlabel = uilabel(app.AnalysisTab);
            app.MPlabel.Text = 'Measuring points to sourcce: ';
            app.rad = uieditfield(app.AnalysisTab, 'numeric');
            app.rad.Limits = [0 Inf];
            app.rad.Value = 150;
            app.mLabel = uilabel(app.AnalysisTab);
            app.mLabel.Text = 'μm';
            app.RTIbox_4 = uicheckbox(app.AnalysisTab);
            app.RTIbox_4.Text = 'Agar (Nicholson 2001)';
            app.RTIbox_5 = uicheckbox(app.AnalysisTab);
            app.RTIbox_5.Text = 'Brain (Nicholson 2001)';
            app.RTIbox_6 = uicheckbox(app.AnalysisTab);
            app.RTIbox_6.Text = ' Brain + clearance (Nicholson 2001)';
            app.Save_2 = uibutton(app.AnalysisTab, 'push');
            app.Save_2.ButtonPushedFcn = createCallbackFcn(app, @Save_2ButtonPushed, true);
            app.Save_2.Icon = 'save.png';
            app.Save_2.Text = '';
            app.Cprofile = uibutton(app.AnalysisTab, 'push');
            app.Cprofile.ButtonPushedFcn = createCallbackFcn(app, @CprofileButtonPushed, true);
            app.Cprofile.Text = 'Concentration profile';
            app.BasalConcentrationuMLabel_2 = uilabel(app.AnalysisTab);
            app.BasalConcentrationuMLabel_2.Text = 'Basal Concentration (uM) ';
            app.Cbasal_2 = uieditfield(app.AnalysisTab, 'numeric');
            app.CurvefittingLabel = uilabel(app.AnalysisTab);
            app.CurvefittingLabel.Text = 'Curve fitting: ';
            app.FitBox = uicheckbox(app.AnalysisTab);
            app.FitBox.Text = '';
            app.AnalyseImageTab = uitab(app.TabGroup);
            app.AnalyseImageTab.Title = 'Analyse Image';
            app.AnalyseImagePanel = uipanel(app.AnalyseImageTab);
            app.AnalyseImagePanel.Title = 'Analyse Image ';
            app.ImageDropDown_4Label = uilabel(app.AnalyseImagePanel);
            app.ImageDropDown_4Label.Text = 'Image';
            app.ImageDropDown_4 = uidropdown(app.AnalyseImagePanel);
            app.LoadButton_3 = uibutton(app.AnalyseImagePanel, 'push');
            app.LoadButton_3.ButtonPushedFcn = createCallbackFcn(app, @LoadButton_3Pushed, true);
            app.LoadButton_3.Icon = 'load.png';
            app.LoadButton_3.Text = 'Load';
            app.AnalyseImagetoestimatevolumefractionandtortuosityLabel = uilabel(app.AnalyseImagePanel);
            app.AnalyseImagetoestimatevolumefractionandtortuosityLabel.Text = 'Analyse Image to estimate volume fraction and tortuosity';
            app.VoidvolumeLabel_3 = uilabel(app.AnalyseImagePanel);
            app.VoidvolumeLabel_3.Text = 'Void volume % (α)';
            app.Alpha_3 = uieditfield(app.AnalyseImagePanel, 'numeric');
            app.Alpha_3.Limits = [0 Inf];
            app.Alpha_3.Editable = 'off';
            app.TortuosityLabel_3 = uilabel(app.AnalyseImagePanel);
            app.TortuosityLabel_3.Text = 'Tortuosity (λ)';
            app.Lamda_3 = uieditfield(app.AnalyseImagePanel, 'numeric');
            app.Lamda_3.Limits = [0 Inf];
            app.Lamda_3.Editable = 'off';
            app.EstimatedPropertiesLabel = uilabel(app.AnalyseImagePanel);
            app.EstimatedPropertiesLabel.Text = 'Estimated Properties';
            app.Ax2_2 = uiaxes(app.AnalyseImageTab);
            title(app.Ax2_2, '')
            xlabel(app.Ax2_2, '')
            ylabel(app.Ax2_2, '')
            app.Ax2_2.Visible = 'off';
            % Show the figure after all components are created
            app.DiffusionModel.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DiffusionModelApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DiffusionModel)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DiffusionModel)
        end
    end
end